classdef ddos_track < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        NumberofDataSetsSliderLabel  matlab.ui.control.Label
        NumberofDataSetsSlider       matlab.ui.control.Slider
        DataTextAreaLabel            matlab.ui.control.Label
        DataTextArea                 matlab.ui.control.TextArea
        StartButton                  matlab.ui.control.Button
        LocationsTextAreaLabel       matlab.ui.control.Label
        LocationsTextArea            matlab.ui.control.TextArea
        DDoSTrackerLabel             matlab.ui.control.Label
        SpecificValueEditFieldLabel  matlab.ui.control.Label
        SpecificValueEditField       matlab.ui.control.NumericEditField
    end


    methods (Access = private)
        function writeIPToFile(app,file,src,dst)
            % Takes the source and destination IPs and writes them to the file and displays them.
            value = sprintf('Writing to File...\nSource IP     Destination IP\n%s %s\n',src,dst);
            fprintf(file, "%s %s\n",src,dst); % USING FPRINTF TO WRITE TO A FILE
            app.DataTextArea.Value = value;
            drawnow
        end
        
    end


    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % Loading text
            drawnow
            app.DataTextArea.Value = 'Scraping...';
            
            % Scrape the url for source and destination addresses, write into array of structures containg the source address and destination address
            options = weboptions;
            options.Timeout = Inf;
            html = string(webread('https://liben002.github.io/delete.html'));
            pattern1 = '<Data Name="SourceAddress">';
            pattern2 = '</Data>';
            srcaddress = extractBetween(html, pattern1, pattern2);
            pattern1 = '<Data Name="DestAddress">';
            dstaddress = extractBetween(html, pattern1, pattern2);
            dat(1) = struct('src', srcaddress(1), 'dst', dstaddress(1));
            for i = 2:length(dstaddress)
                dat(i).src = srcaddress(i);
                dat(i).dst = dstaddress(i);
            end
            
            % Print out the source and destination addresses to the text area
            % updating each time and write the source and destination
            % addresses to a file
            file = fopen("ips.dat", "a");  
            value = sprintf('Writing to File...\nSource IP     Destination IP\n%s %s\n',dat(i).src,dat(i).src);
            app.DataTextArea.Value = value;
            for i = 1:app.NumberofDataSetsSlider.Value
                writeIPToFile(app,file,dat(i).src,dat(i).dst);
            end
            app.DataTextArea.Value = 'Finished Writing to File';
            fclose(file);
            
            % Counts the amount of data points in the IP Address file.
            file = fopen("ips.dat", "r"); 
            count = 0;
            aline = fgetl(file);
            while aline ~= -1
              count = count + 1;
              aline = fgetl(file);
            end
            app.DataTextArea.Value = sprintf('Finished Writing to File\nThere are %d data points',count);
            
            % Each key has 50k requests maximum. DO NOT USE THE FINALS.
            apiKey_Test = 'b68dbd3078324b7db7e03780158ab7a9';
            apiKey_Draft1 = '55772f4b051745228895b806ec1ed17c'; % Used up
            apiKey_Draft2 = '24db145a81284c3bae2aa93915180569'; % Used up
            apiKey_Final1 = '7e3b735fa5e14639a2c112a81e72e6e4';
            apiKey_Final2 = '2e3d7a5071b748c4a80792125c90dfa2';
            apiKey_Final3 = '2f1167b5e30b4a0db0a3c8bba9d9ce90';
            apiKey_Final4 = '';
            
            % Gets the state using the api and writes them to a file.
            countryFID = fopen("countries.dat","a");
            
            HTTPoptions = matlab.net.http.HTTPOptions('ConnectTimeout',20);
            HTTPOptions.Timeout = 20;
            
            value = 'Country Names';
            for i = 1:app.NumberofDataSetsSlider.Value
                drawnow
                try
                    request = matlab.net.http.RequestMessage;
                    URI = sprintf('https://api.ipgeolocation.io/ipgeo?apiKey=%s&ip=%s',apiKey_Final1,dat(i).dst);
                    uri = matlab.net.URI(URI);
                    r = send(request,uri);
                catch
                    app.LocationsTextArea.Value = 'DNE';
                end
                
                %Resets the LocationTextArea every ten countries.
                if mod(i,10) == 0
                        value = 'Country Names';
                end
                        
                % Display the locations while error checking.
                try
                    try
                        value = strcat(value,sprintf('\n%s',erase(r.Body.Data.country_name,' ')));
                        app.LocationsTextArea.Value = value;
                        fprintf(countryFID, "%s\n",erase(r.Body.Data.country_name,' ')); % USING FPRINTF TO WRITE TO A FILE
                    catch
                        value = strcat(value,sprintf('\nPrivate'));
                        app.LocationsTextArea.Value = value;
                        fprintf(countryFID, "%s\n",'Private'); % USING FPRINTF TO WRITE TO A FILE
                    end
                catch
                    app.DataTextArea.Value = 'Could not write data to file';
                end
            end
            
            % Converts the min_max.c file to a mex file and runs it. Output is a file containing the top three countries with
            % the most ip destinations.
            try
                mex -setup
                mex min_max.c
                min_max
            catch
                if isfile("min_max.c") ~= 1
                    disp('min_max.c missing')
                else
                    disp('Please run min_max.c manually')
                end
            end
            
            % Reads the C output file and displays the top three countries.
            country_dataFID = fopen("country_data.dat",'r');
            aline = fgetl(country_dataFID);
            value = 'Top Three Countries That Are Targeted';
            count = 1;
            while aline ~= -1
                top3data(count) = string(aline);
                value = strcat(value,sprintf('\n%s',aline));
                app.LocationsTextArea.Value = value;
                aline = fgetl(country_dataFID);
                count = count + 1;
            end
            for i = 1:length(top3data)
                vals = strsplit(top3data(i));
                xval(i) = vals(1);
                yval(i) = str2double(vals(2));
            end
            bargraph = bar(yval);
            bargraph.Parent.XTickLabel = xval;
            title("Top 3 DDoS Attack Source Countries");
            xlabel("Source Countries");
            ylabel("Number of IP Addresses");
            
            %for i = 1:app.NumberofDataSetsSlider.Value
                %request = matlab.net.http.RequestMessage;
                %uri = matlab.net.URI('http://api.ipstack.com/134.201.250.155?access_key=5363c21471a943a6941adf81243322b0');
                %r = send(request,uri);
                %disp(r.Body.Data.country_code)
            %end
            
            fclose('all');
        end

        % Callback function
        function DataTextAreaValueChanged(app, event)
            value = app.DataTextArea.Value;
            
        end

        % Value changed function: NumberofDataSetsSlider, 
        % SpecificValueEditField
        function SpecificValueEditFieldValueChanged(app, event)
            value = app.SpecificValueEditField.Value;
            app.NumberofDataSetsSlider.Value = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.NumberofDataSetsSliderLabel = uilabel(app.UIFigure);
            app.NumberofDataSetsSliderLabel.Text = 'Number of Data Sets';
            app.NumberofDataSetsSlider = uislider(app.UIFigure);
            app.NumberofDataSetsSlider.Limits = [1 100000];
            app.NumberofDataSetsSlider.MajorTicks = [1 30001 60001 100000];
            app.NumberofDataSetsSlider.ValueChangedFcn = createCallbackFcn(app, @SpecificValueEditFieldValueChanged, true);
            app.NumberofDataSetsSlider.Value = 1;
            app.DataTextAreaLabel = uilabel(app.UIFigure);
            app.DataTextAreaLabel.Text = 'Data';
            app.DataTextArea = uitextarea(app.UIFigure);
            app.DataTextArea.Editable = 'off';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LocationsTextAreaLabel = uilabel(app.UIFigure);
            app.LocationsTextAreaLabel.Text = 'Locations';
            app.LocationsTextArea = uitextarea(app.UIFigure);
            app.LocationsTextArea.Editable = 'off';
            app.DDoSTrackerLabel = uilabel(app.UIFigure);
            app.DDoSTrackerLabel.Text = 'DDoS Tracker';
            app.SpecificValueEditFieldLabel = uilabel(app.UIFigure);
            app.SpecificValueEditFieldLabel.Text = 'Specific Value';
            app.SpecificValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpecificValueEditField.Limits = [1 100000];
            app.SpecificValueEditField.RoundFractionalValues = 'on';
            app.SpecificValueEditField.ValueDisplayFormat = '%.0f';
            app.SpecificValueEditField.ValueChangedFcn = createCallbackFcn(app, @SpecificValueEditFieldValueChanged, true);
            app.SpecificValueEditField.Value = 1;
        end
    end

    methods (Access = public)

        % Construct app
        function app = ddos_track

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end